classdef laserTestBench_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LDSETVoltagemVEditFieldLabel  matlab.ui.control.Label
        LDSETVoltagemVEditField       matlab.ui.control.NumericEditField
        EnableLaserCheckBox           matlab.ui.control.CheckBox
        ODSMedicalIncLaserIncomingInspectionTestBenchV1Rev0110618Label  matlab.ui.control.Label
        Button                        matlab.ui.control.Button
    end


    properties (Access = private)
        ljhandle
        ljudObj
        voltage
        state% Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Make the UD .NET assembly visible in MATLAB.
            ljasm = NET.addAssembly('LJUDDotNet');
            app.ljudObj = LabJack.LabJackUD.LJUD;
            
            % Open the first found LabJack U3.
            [ljerror, app.ljhandle] = app.ljudObj.OpenLabJackS('LJ_dtU3', 'LJ_ctUSB', '0', true, 0);
            
            % Start by using the pin_configuration_reset IOType so that all pin
            % assignments are in the factory default condition.
            app.ljudObj.ePutS(app.ljhandle, 'LJ_ioPIN_CONFIGURATION_RESET', 0, 0, 0);
            
            LDSETVoltagemVEditFieldValueChanged(app);
            EnableLaserCheckBoxValueChanged(app);
        end

        % Value changed function: LDSETVoltagemVEditField
        function LDSETVoltagemVEditFieldValueChanged(app, event)
            app.voltage = 0.001 *app.LDSETVoltagemVEditField.Value;
            channel = 0;
            binary = 0;
            app.ljudObj.eDAC(app.ljhandle, channel, app.voltage, binary, 0, 0);
        end

        % Value changed function: EnableLaserCheckBox
        function EnableLaserCheckBoxValueChanged(app, event)
            app.state = app.EnableLaserCheckBox.Value;
            channel = 4;
            app.ljudObj.eDO(app.ljhandle, channel, app.state);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LDSETVoltagemVEditFieldLabel = uilabel(app.UIFigure);
            app.LDSETVoltagemVEditFieldLabel.Text = 'LD SET Voltage (mV)';
            app.LDSETVoltagemVEditField = uieditfield(app.UIFigure, 'numeric');
            app.LDSETVoltagemVEditField.ValueChangedFcn = createCallbackFcn(app, @LDSETVoltagemVEditFieldValueChanged, true);
            app.EnableLaserCheckBox = uicheckbox(app.UIFigure);
            app.EnableLaserCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableLaserCheckBoxValueChanged, true);
            app.EnableLaserCheckBox.Text = 'Enable Laser';
            app.ODSMedicalIncLaserIncomingInspectionTestBenchV1Rev0110618Label = uilabel(app.UIFigure);
            app.ODSMedicalIncLaserIncomingInspectionTestBenchV1Rev0110618Label.Text = {'ODS Medical Inc.'; 'Laser Incoming Inspection Test Bench'; 'V1 Rev 0'; '11/06/18'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'logo 6 small.png';
            app.Button.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = laserTestBench_v1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end